classdef FreeSpaceOpticalCommunicationsApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        InputTab                       matlab.ui.container.Tab
        AverageTransmittedPower        matlab.ui.control.NumericEditField
        TransmitedPowermWLabel         matlab.ui.control.Label
        CalculateButton                matlab.ui.control.Button
        Modulation                     matlab.ui.control.DropDown
        ModulationDropDownLabel        matlab.ui.control.Label
        AtmosphericConditions          matlab.ui.control.DropDown
        AtmosphericConditionsLabel     matlab.ui.control.Label
        ThetaUnits                     matlab.ui.control.DropDown
        Theta                          matlab.ui.control.NumericEditField
        BeamDivergenceLabel            matlab.ui.control.Label
        Apperture                      matlab.ui.control.EditField
        ApperturecmLabel               matlab.ui.control.Label
        BitRateUnits                   matlab.ui.control.DropDown
        BitRate                        matlab.ui.control.NumericEditField
        RequiredbitrateEditFieldLabel  matlab.ui.control.Label
        LinkLengthkm                   matlab.ui.control.NumericEditField
        LinkLengthkmEditFieldLabel     matlab.ui.control.Label
        Wavelength                     matlab.ui.control.NumericEditField
        WavelengthnmEditFieldLabel     matlab.ui.control.Label
        WhereDropDown                  matlab.ui.control.DropDown
        WhereDropDownLabel             matlab.ui.control.Label
        TransmittedMessage             matlab.ui.control.EditField
        EntermessagetobetransmittedLabel  matlab.ui.control.Label
        TransmitterLabel               matlab.ui.control.Label
        TransmitterReceiver            matlab.ui.container.TabGroup
        Transmitter2Receiver           matlab.ui.container.Tab
        YAxismisalignmentdegreesSlider_default  matlab.ui.control.Slider
        YAxismisalignmentdegreesSlider_2Label_2  matlab.ui.control.Label
        XAxismisalignmentdegreesSlider_default  matlab.ui.control.Slider
        XAxismisalignmentdegreesSlider_2Label_2  matlab.ui.control.Label
        two_stage_transmission         matlab.ui.container.TabGroup
        Transmitter2satTab             matlab.ui.container.Tab
        YAxismisalignmentdegreesSlider_1st_stage  matlab.ui.control.Slider
        YAxismisalignmentdegreesSliderLabel  matlab.ui.control.Label
        XAxismisalignmentdegreesSlider_1st_stage  matlab.ui.control.Slider
        XAxismisalignmentdegreesSliderLabel  matlab.ui.control.Label
        Sat2receiverTab                matlab.ui.container.Tab
        YAxismisalignmentdegreesSlider_2nd_stage  matlab.ui.control.Slider
        YAxismisalignmentdegreesSlider_2Label  matlab.ui.control.Label
        XAxismisalignmentdegreesSlider_2nd_stage  matlab.ui.control.Slider
        XAxismisalignmentdegreesSlider_2Label  matlab.ui.control.Label
        OutputTab                      matlab.ui.container.Tab
        TabGroup3                      matlab.ui.container.TabGroup
        BERvsSNRTab                    matlab.ui.container.Tab
        BERvsSNR                       matlab.ui.control.UIAxes
        BERvsQTab                      matlab.ui.container.Tab
        BERvsQ                         matlab.ui.control.UIAxes
        CapacityvsBandwidthTab         matlab.ui.container.Tab
        CAPACITYvsBANDWIDTH            matlab.ui.control.UIAxes
        BacktotransmitterButton        matlab.ui.control.Button
        QualityFactor                  matlab.ui.control.NumericEditField
        SNREditFieldLabel_2            matlab.ui.control.Label
        SNR                            matlab.ui.control.NumericEditField
        SNREditFieldLabel              matlab.ui.control.Label
        BER                            matlab.ui.control.NumericEditField
        BEREditFieldLabel              matlab.ui.control.Label
        Receivedback                   matlab.ui.control.EditField
        ReceivedbackEditFieldLabel     matlab.ui.control.Label
        ReceiverLabel                  matlab.ui.control.Label
        PointingErrorsdB               matlab.ui.control.Label
        PointingErrors                 matlab.ui.control.NumericEditField
        PointingErrorsLabel            matlab.ui.control.Label
        TurbulanceEffectdB             matlab.ui.control.Label
        TurbulanceEffect               matlab.ui.control.NumericEditField
        TurbulanceEffectLabel          matlab.ui.control.Label
        GeometricalLossesdB            matlab.ui.control.Label
        GeometricalLosses              matlab.ui.control.NumericEditField
        GeometricalLossesLabel         matlab.ui.control.Label
        ScintillationdB                matlab.ui.control.Label
        Scintillation                  matlab.ui.control.NumericEditField
        ScintillationLabel             matlab.ui.control.Label
        AtmosphericAttenuationdB       matlab.ui.control.Label
        AtmosphericAttenuation         matlab.ui.control.NumericEditField
        AtmosphericAttenuationEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        % transmitted % Description
    end
    methods (Access = public)
        function plotter(app,received_binary,sent_binary)
            for i=1:length(received_binary)
                if received_binary(i)==sent_binary(i)
                    bit_error_counter(i)=0;
                elseif received_binary(i)~= sent_binary(i)
                    bit_error_counter(i)=1;
                end
            end
            % x=find(bit_error_counter);
            % the_actual_rate=x/length(received_binary);
            snrdb=1:9/length(received_binary):10-(9/length(received_binary));
            snrlin=10.^(snrdb./10);
            tber=0.5.*erfc(sqrt(snrlin));
            % figure('Name','BERvsSNR');

            % -------------------------------------------------------------
            % BER vs SNR
            semilogy(app.BERvsSNR,snrdb,bit_error_counter,'-bo',snrdb,tber,'-mh');
            % grid on;
            legend(app.BERvsSNR,"measured","expected");
            title(app.BERvsSNR,'Some modulation with AWGN');
            xlabel(app.BERvsSNR,'Signal to noise ratio');
            ylabel(app.BERvsSNR,'Bit error rate');
            % -------------------------------------------------------------
            % BER vs Q
            quality_factor= sqrt(2)*erfcinv(2*bit_error_counter);
            plot(app.BERvsQ,quality_factor,bit_error_counter,'g');
            title(app.BERvsQ,'The quality factor for these signals');
            xlabel(app.BERvsQ,'Quality Factor (Q)');
            ylabel(app.BERvsQ,'Bit error rate');
            % -------------------------------------------------------------
            % Capacity of the system
            % plot(app.CAPACITYvsBANDWIDTH,);
            title(app.CAPACITYvsBANDWIDTH,'Capacity of the system');
            xlabel(app.CAPACITYvsBANDWIDTH,'Bandwdith (B) in MHz');
            ylabel(app.CAPACITYvsBANDWIDTH,'Capacity (C) in bits/second');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            % Inputs
            text = app.TransmittedMessage.Value;
            wavelength = app.Wavelength.Value*1e-9;
            modulation = app.Modulation.Value;
            transmission_location = app.WhereDropDown.Value;
            link_length=app.LinkLengthkm.Value;
            BR=app.BitRate.Value;
            x_misaligment=app.XAxismisalignmentdegreesSlider_1st_stage.Value;
            y_misaligment=app.YAxismisalignmentdegreesSlider_1st_stage.Value;
            misaligment=[x_misaligment,y_misaligment];
            switch app.BitRateUnits.Value
                case "kbps"
                    BR=1e3*BR;
                case "Mbps"
                    BR=1e6*BR;
                case "Gbps"
                    BR=1e9*BR;
            end
            apperture=app.Apperture.Value;
            beam_divergence=app.Theta.Value;
            if app.ThetaUnits.Value=="rad"
                beam_divergence=beam_divergence*180/pi;
            end
            atm_conditions= app.AtmosphericConditions.Value;
            av_transmitted_power= app.AverageTransmittedPower.Value;
            [text_output,ber,snr,total_losses,received_binary,sent_binary] = fso_simulation(text, wavelength, modulation,transmission_location,link_length,BR,apperture,beam_divergence,atm_conditions,misaligment,av_transmitted_power);            %
            
            % Outputs
            app.Receivedback.Value=text_output;
            app.BER.Value=ber;
            app.SNR.Value=snr;
            app.QualityFactor.Value=sqrt(snr)/2;
            app.GeometricalLosses.Value=total_losses(3);
            app.AtmosphericAttenuation.Value=total_losses(1);
            app.Scintillation.Value=total_losses(2);
            app.TabGroup.SelectedTab = app.OutputTab;

            % Plot the data
            plotter(app,received_binary,sent_binary);
            
        end

        % Value changed function: WhereDropDown
        function WhereDropDownValueChanged(app, event)
            % Visibility toggle
            switch app.WhereDropDown.Value 
                case "Ground via sat relay"
                    app.LinkLengthkm.Visible ="off";
                    app.LinkLengthkmEditFieldLabel.Visible = "off";
                    app.TransmitterReceiver.Visible="off";
                    app.two_stage_transmission.Visible="on";
                case "Cosmic Space from earth"
                    app.LinkLengthkm.Visible="on";
                    app.LinkLengthkmEditFieldLabel.Visible = "on";
                    app.TransmitterReceiver.Visible="off";
                    app.two_stage_transmission.Visible="on";
                otherwise
                    app.LinkLengthkm.Visible="on";
                    app.LinkLengthkmEditFieldLabel.Visible = "on";
                    app.TransmitterReceiver.Visible="on";
                    app.two_stage_transmission.Visible="off";
            end
        end

        % Button pushed function: BacktotransmitterButton
        function BacktotransmitterButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.InputTab;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InputTab = uitab(app.TabGroup);
            app.InputTab.Title = 'Input';
            app.two_stage_transmission = uitabgroup(app.InputTab);
            app.Transmitter2satTab = uitab(app.two_stage_transmission);
            app.Transmitter2satTab.Title = 'Transmitter to sat';
            app.XAxismisalignmentdegreesSliderLabel = uilabel(app.Transmitter2satTab);
            app.XAxismisalignmentdegreesSliderLabel.Text = {'X Axis'; 'misalignment'; '(degrees)'};
            app.XAxismisalignmentdegreesSlider_1st_stage = uislider(app.Transmitter2satTab);
            app.XAxismisalignmentdegreesSlider_1st_stage.Limits = [-60 60];
            app.XAxismisalignmentdegreesSlider_1st_stage.MajorTicks = [-60 -45 -30 -15 0 15 30 45 60];
            app.XAxismisalignmentdegreesSlider_1st_stage.MajorTickLabels = {'-60', '-45', '-30', '-15', '0', '15', '30', '45', '60'};
            app.YAxismisalignmentdegreesSliderLabel = uilabel(app.Transmitter2satTab);
            app.YAxismisalignmentdegreesSliderLabel.Text = {'Y Axis'; 'misalignment'; '(degrees)'};
            app.YAxismisalignmentdegreesSlider_1st_stage = uislider(app.Transmitter2satTab);
            app.YAxismisalignmentdegreesSlider_1st_stage.Limits = [-60 60];
            app.YAxismisalignmentdegreesSlider_1st_stage.MajorTicks = [-60 -45 -30 -15 0 15 30 45 60];
            app.YAxismisalignmentdegreesSlider_1st_stage.MajorTickLabels = {'-60', '-45', '-30', '-15', '0', '15', '30', '45', '60'};
            app.Sat2receiverTab = uitab(app.two_stage_transmission);
            app.Sat2receiverTab.Title = 'Sat to receiver';
            app.XAxismisalignmentdegreesSlider_2Label = uilabel(app.Sat2receiverTab);
            app.XAxismisalignmentdegreesSlider_2Label.Text = {'X Axis'; 'misalignment'; '(degrees)'};
            app.XAxismisalignmentdegreesSlider_2nd_stage = uislider(app.Sat2receiverTab);
            app.XAxismisalignmentdegreesSlider_2nd_stage.Limits = [-60 60];
            app.XAxismisalignmentdegreesSlider_2nd_stage.MajorTicks = [-60 -45 -30 -15 0 15 30 45 60];
            app.YAxismisalignmentdegreesSlider_2Label = uilabel(app.Sat2receiverTab);
            app.YAxismisalignmentdegreesSlider_2Label.Text = {'Y Axis'; 'misalignment'; '(degrees)'};
            app.YAxismisalignmentdegreesSlider_2nd_stage = uislider(app.Sat2receiverTab);
            app.YAxismisalignmentdegreesSlider_2nd_stage.Limits = [-60 60];
            app.YAxismisalignmentdegreesSlider_2nd_stage.MajorTicks = [-60 -45 -30 -15 0 15 30 45 60];
            app.TransmitterReceiver = uitabgroup(app.InputTab);
            app.Transmitter2Receiver = uitab(app.TransmitterReceiver);
            app.Transmitter2Receiver.Title = 'Sat to receiver';
            app.XAxismisalignmentdegreesSlider_2Label_2 = uilabel(app.Transmitter2Receiver);
            app.XAxismisalignmentdegreesSlider_2Label_2.Text = {'X Axis'; 'misalignment'; '(degrees)'};
            app.XAxismisalignmentdegreesSlider_default = uislider(app.Transmitter2Receiver);
            app.XAxismisalignmentdegreesSlider_default.Limits = [-60 60];
            app.XAxismisalignmentdegreesSlider_default.MajorTicks = [-60 -45 -30 -15 0 15 30 45 60];
            app.YAxismisalignmentdegreesSlider_2Label_2 = uilabel(app.Transmitter2Receiver);
            app.YAxismisalignmentdegreesSlider_2Label_2.Text = {'Y Axis'; 'misalignment'; '(degrees)'};
            app.YAxismisalignmentdegreesSlider_default = uislider(app.Transmitter2Receiver);
            app.YAxismisalignmentdegreesSlider_default.Limits = [-60 60];
            app.YAxismisalignmentdegreesSlider_default.MajorTicks = [-60 -45 -30 -15 0 15 30 45 60];
            app.TransmitterLabel = uilabel(app.InputTab);
            app.TransmitterLabel.Text = 'Transmitter';
            app.EntermessagetobetransmittedLabel = uilabel(app.InputTab);
            app.EntermessagetobetransmittedLabel.Text = {'Enter message'; 'to be transmitted:'};
            app.TransmittedMessage = uieditfield(app.InputTab, 'text');
            app.TransmittedMessage.Value = 'THE QUICK BROWN FOX JUMPED OVER THE LAZY DOG. ';
            app.WhereDropDownLabel = uilabel(app.InputTab);
            app.WhereDropDownLabel.Text = 'Where?';
            app.WhereDropDown = uidropdown(app.InputTab);
            app.WhereDropDown.Items = {'Cosmic space only', 'Ground only', 'Ground via sat relay', 'Cosmic Space from earth'};
            app.WhereDropDown.ValueChangedFcn = createCallbackFcn(app, @WhereDropDownValueChanged, true);
            app.WhereDropDown.Value = 'Cosmic space only';
            app.WavelengthnmEditFieldLabel = uilabel(app.InputTab);
            app.WavelengthnmEditFieldLabel.Text = 'Wavelength (nm)';
            app.Wavelength = uieditfield(app.InputTab, 'numeric');
            app.Wavelength.Value = 1550;
            app.LinkLengthkmEditFieldLabel = uilabel(app.InputTab);
            app.LinkLengthkmEditFieldLabel.Text = 'Link Length (km)';
            app.LinkLengthkm = uieditfield(app.InputTab, 'numeric');
            app.LinkLengthkm.Value = 2;
            app.RequiredbitrateEditFieldLabel = uilabel(app.InputTab);
            app.RequiredbitrateEditFieldLabel.Text = 'Required bit rate';
            app.BitRate = uieditfield(app.InputTab, 'numeric');
            app.BitRate.Value = 1;
            app.BitRateUnits = uidropdown(app.InputTab);
            app.BitRateUnits.Items = {'bps', 'kbps', 'Mbps', 'Gbps'};
            app.BitRateUnits.Value = 'Mbps';
            app.ApperturecmLabel = uilabel(app.InputTab);
            app.ApperturecmLabel.Text = 'Apperture (m)';
            app.Apperture = uieditfield(app.InputTab, 'text');
            app.Apperture.Value = '0.2';
            app.BeamDivergenceLabel = uilabel(app.InputTab);
            app.BeamDivergenceLabel.Text = 'Beam Divergence';
            app.Theta = uieditfield(app.InputTab, 'numeric');
            app.Theta.Value = 0.002;
            app.ThetaUnits = uidropdown(app.InputTab);
            app.ThetaUnits.Items = {'rad', 'degrees'};
            app.ThetaUnits.Value = 'rad';
            app.AtmosphericConditionsLabel = uilabel(app.InputTab);
            app.AtmosphericConditionsLabel.Text = {'Atmospheric'; 'Conditions'};
            app.AtmosphericConditions = uidropdown(app.InputTab);
            app.AtmosphericConditions.Items = {'Very clear air', 'Clear air', 'Very light mist', 'Light mist', 'Very light fog', 'Light fog', 'Moderate fog', 'Thick fog', 'Dense fog'};
            app.AtmosphericConditions.Value = 'Very clear air';
            app.ModulationDropDownLabel = uilabel(app.InputTab);
            app.ModulationDropDownLabel.Text = 'Modulation ';
            app.Modulation = uidropdown(app.InputTab);
            app.Modulation.Items = {'OOK', 'QPSK', '16 QAM', 'No modulation'};
            app.Modulation.Value = 'OOK';
            app.CalculateButton = uibutton(app.InputTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate!';
            app.TransmitedPowermWLabel = uilabel(app.InputTab);
            app.TransmitedPowermWLabel.Text = {'Average Transmited '; 'Power (mW)'};
            app.AverageTransmittedPower = uieditfield(app.InputTab, 'numeric');
            app.AverageTransmittedPower.Value = 0.2;
            app.OutputTab = uitab(app.TabGroup);
            app.OutputTab.Title = 'Output';
            app.AtmosphericAttenuationEditFieldLabel = uilabel(app.OutputTab);
            app.AtmosphericAttenuationEditFieldLabel.Text = 'Atmospheric Attenuation: ';
            app.AtmosphericAttenuation = uieditfield(app.OutputTab, 'numeric');
            app.AtmosphericAttenuationdB = uilabel(app.OutputTab);
            app.AtmosphericAttenuationdB.Text = 'dB';
            app.ScintillationLabel = uilabel(app.OutputTab);
            app.ScintillationLabel.Text = 'Scintillation: ';
            app.Scintillation = uieditfield(app.OutputTab, 'numeric');
            app.ScintillationdB = uilabel(app.OutputTab);
            app.ScintillationdB.Text = 'dB';
            app.GeometricalLossesLabel = uilabel(app.OutputTab);
            app.GeometricalLossesLabel.Text = 'Geometrical Losses';
            app.GeometricalLosses = uieditfield(app.OutputTab, 'numeric');
            app.GeometricalLossesdB = uilabel(app.OutputTab);
            app.GeometricalLossesdB.Text = 'dB';
            app.TurbulanceEffectLabel = uilabel(app.OutputTab);
            app.TurbulanceEffectLabel.Text = 'Turbulance Effect';
            app.TurbulanceEffect = uieditfield(app.OutputTab, 'numeric');
            app.TurbulanceEffectdB = uilabel(app.OutputTab);
            app.TurbulanceEffectdB.Text = 'dB';
            app.PointingErrorsLabel = uilabel(app.OutputTab);
            app.PointingErrorsLabel.Text = 'Pointing Errors';
            app.PointingErrors = uieditfield(app.OutputTab, 'numeric');
            app.PointingErrorsdB = uilabel(app.OutputTab);
            app.PointingErrorsdB.Text = 'dB';
            app.ReceiverLabel = uilabel(app.OutputTab);
            app.ReceiverLabel.Text = 'Receiver';
            app.ReceivedbackEditFieldLabel = uilabel(app.OutputTab);
            app.ReceivedbackEditFieldLabel.Text = 'Received back';
            app.Receivedback = uieditfield(app.OutputTab, 'text');
            app.Receivedback.Value = 'Input message to transmit in input tab';
            app.BEREditFieldLabel = uilabel(app.OutputTab);
            app.BEREditFieldLabel.Text = 'BER';
            app.BER = uieditfield(app.OutputTab, 'numeric');
            app.SNREditFieldLabel = uilabel(app.OutputTab);
            app.SNREditFieldLabel.Text = 'SNR (dB)';
            app.SNR = uieditfield(app.OutputTab, 'numeric');
            app.SNREditFieldLabel_2 = uilabel(app.OutputTab);
            app.SNREditFieldLabel_2.Text = 'Quality Factor';
            app.QualityFactor = uieditfield(app.OutputTab, 'numeric');
            app.BacktotransmitterButton = uibutton(app.OutputTab, 'push');
            app.BacktotransmitterButton.ButtonPushedFcn = createCallbackFcn(app, @BacktotransmitterButtonPushed, true);
            app.BacktotransmitterButton.Text = 'Back to transmitter';
            app.TabGroup3 = uitabgroup(app.OutputTab);
            app.BERvsSNRTab = uitab(app.TabGroup3);
            app.BERvsSNRTab.Title = 'BER vs SNR';
            app.BERvsSNR = uiaxes(app.BERvsSNRTab);
            title(app.BERvsSNR, 'Title')
            xlabel(app.BERvsSNR, 'X')
            ylabel(app.BERvsSNR, 'Y')
            zlabel(app.BERvsSNR, 'Z')
            app.BERvsQTab = uitab(app.TabGroup3);
            app.BERvsQTab.Title = 'BER vs Q';
            app.BERvsQ = uiaxes(app.BERvsQTab);
            title(app.BERvsQ, 'Title')
            xlabel(app.BERvsQ, 'X')
            ylabel(app.BERvsQ, 'Y')
            zlabel(app.BERvsQ, 'Z')
            app.CapacityvsBandwidthTab = uitab(app.TabGroup3);
            app.CapacityvsBandwidthTab.Title = 'Capacity vs Bandwidth';
            app.CAPACITYvsBANDWIDTH = uiaxes(app.CapacityvsBandwidthTab);
            title(app.CAPACITYvsBANDWIDTH, 'Title')
            xlabel(app.CAPACITYvsBANDWIDTH, 'X')
            ylabel(app.CAPACITYvsBANDWIDTH, 'Y')
            zlabel(app.CAPACITYvsBANDWIDTH, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FreeSpaceOpticalCommunicationsApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end